classdef ACapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AC1D                           matlab.ui.Figure
        liczbaepokSpinnerLabel         matlab.ui.control.Label
        liczbaepokSpinner              matlab.ui.control.Spinner
        liczbakomrekSpinnerLabel       matlab.ui.control.Label
        liczbakomrekSpinner            matlab.ui.control.Spinner
        warunkiperiodyczneButtonGroup  matlab.ui.container.ButtonGroup
        TAKButton                      matlab.ui.control.RadioButton
        NIEButton                      matlab.ui.control.RadioButton
        PokawynikButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        reguaButtonGroup               matlab.ui.container.ButtonGroup
        Button                         matlab.ui.control.RadioButton
        Button_2                       matlab.ui.control.RadioButton
        Button_3                       matlab.ui.control.RadioButton
        Button_4                       matlab.ui.control.RadioButton
        Button_5                       matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        tabR = [ 0; 0; 0; 0; 0; 0; 0; 0]; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function reguaListBoxValueChanged(app, event)
          
        end

        % Button pushed function: PokawynikButton
        function PokawynikButtonPushed(app, event)
            
        epok = app.liczbaepokSpinner.Value;
        nr_of_cells = app.liczbakomrekSpinner.Value;
        
        universe = zeros(nr_of_cells+2, epok);
        universe(2 : nr_of_cells+1, 1) = 0;
        universe(nr_of_cells/2, 1) = 1;
        
        tab_hood = [ 0 0 0; 0 0 1; 0 1 0; 0 1 1; 1 0 0; 1 0 1; 1 1 0; 1 1 1];
      
        for time = 2 : epok
            
             %warunek brzegowy
             if app.TAKButton.Value 
                  universe(1,time-1) = universe(nr_of_cells+1,time-1);
                  universe(nr_of_cells+2,time-1) = universe(2,time-1);
             end
            
            for n = 2 : nr_of_cells + 1
                for i = 1 : 8
                    if [universe(n-1,time-1), universe(n,time-1), universe(n+1,time-1)] == tab_hood(i,:)
                        universe(n,time) = app.tabR(i);
                    end
                end
            end 
            imshow(universe','parent',app.UIAxes)
        end
          
        end

        % Selection changed function: reguaButtonGroup
        function reguaButtonGroupSelectionChanged(app, event)
            selectedButton = app.reguaButtonGroup.SelectedObject;
            
            if selectedButton == app.Button
                app.tabR = [    0;     1;     1;     1;     1;     0;     0;     0];
            end
             if selectedButton == app.Button_2
                app.tabR = [    0;     0;     1;     1;     1;     1;     0;     0];
             end
             if selectedButton == app.Button_3
                app.tabR = [    0;     1;     0;     1;     1;     0;     1;     0];
             end
             if selectedButton == app.Button_4
                app.tabR = [    0;     0;     0;     1;     1;     1;     1;     0];
             end
             if selectedButton == app.Button_5
                 app.tabR = [    1;     0;     0;     0;     0;     1;     1;     1];
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AC1D = uifigure('Visible', 'off');
            app.AC1D.Name = 'UI Figure';
            app.liczbaepokSpinnerLabel = uilabel(app.AC1D);
            app.liczbaepokSpinnerLabel.Text = 'liczba epok';
            app.liczbaepokSpinner = uispinner(app.AC1D);
            app.liczbaepokSpinner.Limits = [1 Inf];
            app.liczbaepokSpinner.Value = 1;
            app.liczbakomrekSpinnerLabel = uilabel(app.AC1D);
            app.liczbakomrekSpinnerLabel.Text = 'liczba komórek';
            app.liczbakomrekSpinner = uispinner(app.AC1D);
            app.liczbakomrekSpinner.Limits = [1 Inf];
            app.liczbakomrekSpinner.Value = 1;
            app.warunkiperiodyczneButtonGroup = uibuttongroup(app.AC1D);
            app.warunkiperiodyczneButtonGroup.Title = 'warunki periodyczne';
            app.TAKButton = uiradiobutton(app.warunkiperiodyczneButtonGroup);
            app.TAKButton.Text = 'TAK';
            app.TAKButton.Value = true;
            app.NIEButton = uiradiobutton(app.warunkiperiodyczneButtonGroup);
            app.NIEButton.Text = 'NIE';
            app.PokawynikButton = uibutton(app.AC1D, 'push');
            app.PokawynikButton.ButtonPushedFcn = createCallbackFcn(app, @PokawynikButtonPushed, true);
            app.PokawynikButton.Text = 'Pokaż wynik';
            app.UIAxes = uiaxes(app.AC1D);
            title(app.UIAxes, 'Wyniki')
            xlabel(app.UIAxes, 'tablica')
            ylabel(app.UIAxes, 'epoki')
            app.reguaButtonGroup = uibuttongroup(app.AC1D);
            app.reguaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @reguaButtonGroupSelectionChanged, true);
            app.reguaButtonGroup.Title = 'reguła';
            app.Button = uiradiobutton(app.reguaButtonGroup);
            app.Button.Text = '30';
            app.Button_2 = uiradiobutton(app.reguaButtonGroup);
            app.Button_2.Text = '60';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.reguaButtonGroup);
            app.Button_3.Text = '90';
            app.Button_4 = uiradiobutton(app.reguaButtonGroup);
            app.Button_4.Text = '120';
            app.Button_5 = uiradiobutton(app.reguaButtonGroup);
            app.Button_5.Text = '255';
            % Show the figure after all components are created
            app.AC1D.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ACapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AC1D)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AC1D)
        end
    end
end